classdef lab2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ZLabel_3               matlab.ui.control.Label
        YLabel_2               matlab.ui.control.Label
        ZLabel_2               matlab.ui.control.Label
        XSlider                matlab.ui.control.Slider
        XLabel                 matlab.ui.control.Label
        YSlider                matlab.ui.control.Slider
        YLabel                 matlab.ui.control.Label
        ZSlider                matlab.ui.control.Slider
        ZLabel                 matlab.ui.control.Label
        FocalLengthSlider      matlab.ui.control.Slider
        CameraBaselineSlider   matlab.ui.control.Slider
        CameraBaselinemmLabel  matlab.ui.control.Label
        FocalLengthmmLabel     matlab.ui.control.Label
        LeftCamera             matlab.ui.control.UIAxes
        RightCamera            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ps = 0.006;
        xNumPix = 752; % image width in pixels
        yNumPix = 480; % image height in pixels
        
        b = 60            % Baseline in mm
        f = 6             % Focal length in mm
        Xval = 0          % X in meters
        Yval = 0          % Y in meters
        Zval = 10          % Z in meters

        camLeft % left camera
        camRight % right camera
    end
    
    methods (Access = private)
        
        function updateDisplays(app)
            app.XLabel.Text = sprintf('X = %.2f m', app.Xval);
            app.YLabel.Text = sprintf('Y = %.2f m', app.Yval);
            app.ZLabel.Text = sprintf('Z = %.2f m', app.Zval);

            app.camLeft.f  = app.f / 1000;
            app.camRight.f = app.f / 1000;
            half_b_m = (app.b / 1000) / 2;
            app.camLeft.T  = SE3(-half_b_m, 0, 0);
            app.camRight.T = SE3(+half_b_m, 0, 0);

            P = [app.Xval; app.Yval; app.Zval];
            leftPixel  = app.camLeft.project(P);
            rightPixel = app.camRight.project(P);

            cla(app.LeftCamera);
            cla(app.RightCamera);
            hold(app.LeftCamera,  'on');
            hold(app.RightCamera, 'on');

            scatter(app.LeftCamera,  leftPixel(1),  leftPixel(2), 50, 'b','filled');
            scatter(app.RightCamera, rightPixel(1), rightPixel(2), 50, 'r','filled');

            app.LeftCamera.XLim = [0 app.xNumPix];
            app.LeftCamera.YLim = [0 app.yNumPix];
            set(app.LeftCamera, 'YDir','reverse');

            app.RightCamera.XLim = [0 app.xNumPix];
            app.RightCamera.YLim = [0 app.yNumPix];
            set(app.RightCamera, 'YDir','reverse');

            title(app.LeftCamera, 'Left Camera');
            title(app.RightCamera,'Right Camera');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.camLeft = CentralCamera(...
                'focal', app.f/1000, ...
                'pixel', app.ps/1000, ...
                'resolution', [app.xNumPix,app.yNumPix], ...
                'centre', [app.xNumPix/2,app.yNumPix/2], ...
                'name', 'LeftCamera');
            app.camLeft.T = SE3(-0.03, 0, 0);

            app.camRight = CentralCamera(...
                'focal', app.f/1000, ...
                'pixel', app.ps/1000, ...
                'resolution', [app.xNumPix,app.yNumPix], ...
                'centre', [app.xNumPix/2,app.yNumPix/2], ...
                'name', 'RightCamera');
            app.camRight.T = SE3(0.03, 0, 0);

            app.XSlider.Value = app.Xval;
            app.YSlider.Value = app.Yval;
            app.ZSlider.Value = app.Zval;
            app.CameraBaselineSlider.Value = app.b;
            app.FocalLengthSlider.Value = app.f;

            updateDisplays(app);
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            app.Xval = event.Value;
            updateDisplays(app);
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            app.Yval = event.Value;
            updateDisplays(app);
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            app.Zval = event.Value;
            updateDisplays(app);
        end

        % Value changing function: CameraBaselineSlider
        function CameraBaselineSliderValueChanging(app, event)
            app.b = event.Value;
            updateDisplays(app);
        end

        % Value changing function: FocalLengthSlider
        function FocalLengthSliderValueChanging(app, event)
            app.f = event.Value;
            updateDisplays(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RightCamera = uiaxes(app.UIFigure);
            title(app.RightCamera, 'Camera Right')
            xlabel(app.RightCamera, 'X')
            ylabel(app.RightCamera, 'Y')
            zlabel(app.RightCamera, 'Z')
            app.LeftCamera = uiaxes(app.UIFigure);
            title(app.LeftCamera, 'Camera Left')
            xlabel(app.LeftCamera, 'X')
            ylabel(app.LeftCamera, 'Y')
            zlabel(app.LeftCamera, 'Z')
            app.FocalLengthmmLabel = uilabel(app.UIFigure);
            app.FocalLengthmmLabel.Text = {'Focal Length'; '[mm]'};
            app.CameraBaselinemmLabel = uilabel(app.UIFigure);
            app.CameraBaselinemmLabel.Text = {'Camera Baseline'; '[mm]'};
            app.CameraBaselineSlider = uislider(app.UIFigure);
            app.CameraBaselineSlider.ValueChangingFcn = createCallbackFcn(app, @CameraBaselineSliderValueChanging, true);
            app.FocalLengthSlider = uislider(app.UIFigure);
            app.FocalLengthSlider.Limits = [0 20];
            app.FocalLengthSlider.ValueChangingFcn = createCallbackFcn(app, @FocalLengthSliderValueChanging, true);
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = '';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [0 10];
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = '';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [0 5];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = '';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [0 5];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.ZLabel_2 = uilabel(app.UIFigure);
            app.ZLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.UIFigure);
            app.YLabel_2.Text = 'Y';
            app.ZLabel_3 = uilabel(app.UIFigure);
            app.ZLabel_3.Text = 'Z';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end