classdef lab3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button       matlab.ui.control.Button
        ZLabel       matlab.ui.control.Label
        YLabel       matlab.ui.control.Label
        XLabel       matlab.ui.control.Label
        UIAxesRight  matlab.ui.control.UIAxes
        UIAxesLeft   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        f = 6
        b = 60

        ps = 0.006

        xNumPix = 752
        yNumPix = 480

        camLeft
        camRight
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            focal_m = app.f / 1000;
            pixel_m = app.ps / 1000;

            app.camLeft = CentralCamera('focal', focal_m, 'pixel', pixel_m, 'resolution', [app.xNumPix, app.yNumPix], 'centre', [app.xNumPix/2, app.yNumPix/2], 'name', 'LeftCamera');
            app.camLeft.T = SE3(-(app.b/2)/1000, 0, 0);

            app.camRight = CentralCamera('focal', focal_m, 'pixel', pixel_m, 'resolution', [app.xNumPix, app.yNumPix], 'centre', [app.xNumPix/2, app.yNumPix/2], 'name', 'RightCamera');
            app.camRight.T = SE3((app.b/2)/1000, 0, 0);

            app.XLabel.Text = 'X = ?';
            app.YLabel.Text = 'Y = ?';
            app.ZLabel.Text = 'Z = ?';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [lf, lp] = uigetfile({'*.png;*.jpg'}, 'Select LEFT image');
            if isequal(lf,0), return; end
            leftPath = fullfile(lp, lf);

            [rf, rp] = uigetfile({'*.png;*.jpg'}, 'Select RIGHT image');
            if isequal(rf,0), return; end
            rightPath = fullfile(rp, rf);

            leftImg  = imread(leftPath);
            rightImg = imread(rightPath);

            imshow(leftImg, 'Parent', app.UIAxesLeft);
            title(app.UIAxesLeft, 'Left Image');

            imshow(rightImg, 'Parent', app.UIAxesRight);
            title(app.UIAxesRight, 'Right Image');

            grayLeft  = rgb2gray(leftImg);
            grayRight = rgb2gray(rightImg);

            BW_left  = grayLeft > 200;  
            BW_right = grayRight > 200;

            if exist('bwareaopen','file')
                BW_left  = bwareaopen(BW_left,  50);
                BW_right = bwareaopen(BW_right, 50);
            end
    
            minBallArea = 3000;
            maxBallArea = 5000;

            statsL = regionprops(BW_left,  'Centroid','Area');
            statsR = regionprops(BW_right, 'Centroid','Area');

            [centers, radii, metric] = imfindcircles(leftImg, [40 90])

            viscircles(app.UIAxesLeft, centers, radii,'EdgeColor','b');


            ballCandidatesL = statsL([statsL.Area] >= minBallArea & [statsL.Area] <= maxBallArea);
            if isempty(ballCandidatesL)
                uialert(app.UIFigure, 'Ball not found in left image.', 'Detection Error');
                return;
            end

            [~, idxL] = max([ballCandidatesL.Area]);
            centroidL = ballCandidatesL(idxL).Centroid;  % [xL, yL]

            ballCandidatesR = statsR([statsR.Area] >= minBallArea & [statsR.Area] <= maxBallArea);
            if isempty(ballCandidatesR)
                uialert(app.UIFigure, 'Ball not found in right image.', 'Detection Error');
                return;
            end

            [~, idxR] = max([ballCandidatesR.Area]);
            centroidR = ballCandidatesR(idxR).Centroid;  % [xR, yR]

            % Mark them on the axes
            hold(app.UIAxesLeft, 'on');
            plot(app.UIAxesLeft, centroidL(1), centroidL(2), 'r+', 'MarkerSize',10,'LineWidth',2);
            hold(app.UIAxesLeft, 'off');

            hold(app.UIAxesRight, 'on');
            plot(app.UIAxesRight, centroidR(1), centroidR(2), 'g+', 'MarkerSize',10,'LineWidth',2);
            hold(app.UIAxesRight, 'off');

            xL = centroidL(1);
            yL = centroidL(2);
            xR = centroidR(1);
            yR = centroidR(2);

            cx = app.xNumPix/2;
            cy = app.yNumPix/2;

            d = (abs((xL-cx)-(xR-cx))*app.ps);
            
            Z_mm = (app.b*app.f)./d;
            X_mm = ((yL-cx) * Z_mm / app.f) * app.ps;
            Y_mm = ((yL-cy) * Z_mm / app.f) * app.ps;
            X = X_mm / 1000;
            Y = Y_mm / 1000;
            Z = Z_mm / 1000;

            % Show results
            app.XLabel.Text = sprintf('X = %.3f m', X);
            app.YLabel.Text = sprintf('Y = %.3f m', Y);
            app.ZLabel.Text = sprintf('Z = %.3f m', Z);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesLeft = uiaxes(app.UIFigure);
            title(app.UIAxesLeft, 'Title')
            xlabel(app.UIAxesLeft, 'X')
            ylabel(app.UIAxesLeft, 'Y')
            zlabel(app.UIAxesLeft, 'Z')
            app.UIAxesRight = uiaxes(app.UIFigure);
            title(app.UIAxesRight, 'Title')
            xlabel(app.UIAxesRight, 'X')
            ylabel(app.UIAxesRight, 'Y')
            zlabel(app.UIAxesRight, 'Z')
            app.XLabel = uilabel(app.UIFigure);
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Label2';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Label3';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end