classdef lab4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        XLabel         matlab.ui.control.Label
        YLabel         matlab.ui.control.Label
        ZLabel         matlab.ui.control.Label
        CaptureButton  matlab.ui.control.Button
        PlotButton     matlab.ui.control.Button
        UIAxisLeft     matlab.ui.control.UIAxes
        UIAxisRight    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Ball position
        ballX = .5
        ballY = .5
        ballZ = 3

        % Camera parameters
        f = 7            % focal length in mm
        b = 60           % baseline in mm
        ps = 0.006       % pixel size in mm
        xNumPix = 752    % image width in pixels
        yNumPix = 480    % image height in pixels

        blenderClient
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize label text
            app.XLabel.Text = 'X = ?';
            app.YLabel.Text = 'Y = ?';
            app.ZLabel.Text = 'Z = ?';

            % Initialize axes text
            title(app.UIAxisLeft, 'Left Image from Blender');
            title(app.UIAxisRight, 'Right Image from Blender');

            ipAddress = '127.0.0.1';
            port = 55001;
            app.blenderClient = tcpclient(ipAddress, port, "Timeout", 20);
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            ball = blenderLink(app.blenderClient, app.xNumPix, app.yNumPix, app.ballX, app.ballY, app.ballZ, 0, 0, 0, "tennisBall");
            leftImg = blenderLink(app.blenderClient, app.xNumPix, app.yNumPix, 0, -0.03, 10, 0, 0, 90, 'Camera');
            rightImg = blenderLink(app.blenderClient, app.xNumPix, app.yNumPix, 0, +0.03, 10, 0, 0, 90, 'Camera');

            imshow(leftImg, 'Parent', app.UIAxisLeft);
            imshow(rightImg, 'Parent', app.UIAxisRight);

            [centersL, radiiL] = imfindcircles(leftImg, [20 200]);
            if isempty(centersL)
                uialert(app.UIFigure, 'No ball detected in left image.', 'Detection Error');
                return;
            end
            centerLeft = centersL(1,:);
            radiusLeft = radiiL(1);

            [centersR, radiiR] = imfindcircles(rightImg, [20 200]);
            if isempty(centersR)
                uialert(app.UIFigure, 'No ball detected in right image.', 'Detection Error');
                return;
            end
            centerRight = centersR(1,:);
            radiusRight = radiiR(1);

            viscircles(app.UIAxisLeft, centerLeft, radiusLeft, 'EdgeColor','b');
            hold(app.UIAxisLeft, 'on');
            plot(app.UIAxisLeft, centerLeft(1), centerLeft(2), ...
                'r+', 'MarkerSize', 10, 'LineWidth', 2);
            hold(app.UIAxisLeft, 'off');

            viscircles(app.UIAxisRight, centerRight, radiusRight, 'EdgeColor','b');
            hold(app.UIAxisRight, 'on');
            plot(app.UIAxisRight, centerRight(1), centerRight(2), ...
                'g+', 'MarkerSize', 10, 'LineWidth', 2);
            hold(app.UIAxisRight, 'off');

            cx = app.xNumPix/2;
            cy = app.yNumPix/2;

            % centerLeft = [xL, yL]
            xL = centerLeft(1);
            yL = centerLeft(2);
            % centerRight = [xR, yR]
            xR = centerRight(1);
            yR = centerRight(2);

            % Disparity in mm
            d_mm = abs( (xL - cx) - (xR - cx) ) * app.ps;

            % Z in mm = (b * f) / disparity
            Z_mm = (app.b * app.f) / d_mm;

            % X_mm, Y_mm
            X_mm = ((xL - cx) * Z_mm / app.f) * app.ps;
            Y_mm = ((yL - cy) * Z_mm / app.f) * app.ps;

            % Convert mm to meters
            X = X_mm / 1000;
            Y = Y_mm / 1000;
            Z = Z_mm / 1000;

            % 7) Update labels in the GUI
            app.XLabel.Text = sprintf('X = %.3f m', X);
            app.YLabel.Text = sprintf('Y = %.3f m', Y);
            app.ZLabel.Text = sprintf('Z = %.3f m', Z);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            distancesActual = 1:5;
            distancesMeasured = zeros(size(distancesActual));

            for i = 1:length(distancesActual)
                ball = blenderLink(app.blenderClient, app.xNumPix, app.yNumPix, app.ballX, app.ballY, distancesActual(i), 0, 0, 0, "tennisBall");

                % Capture images for each distance
                leftImg = blenderLink(app.blenderClient, app.xNumPix, app.yNumPix, 0, -0.03, 10, 0, 0, 90, 'Camera');
                rightImg = blenderLink(app.blenderClient, app.xNumPix, app.yNumPix, 0, +0.03, 10, 0, 0, 90, 'Camera');

                % Detect
                [centersL, ~] = imfindcircles(leftImg, [20 200]);
                [centersR, ~] = imfindcircles(rightImg, [20 200]);

                if isempty(centersL) || isempty(centersR)
                    distancesMeasured(i) = NaN;
                    continue;
                end
                cL = centersL(1,:); 
                cR = centersR(1,:);

                % Stereo math
                cx = app.xNumPix/2; 
                cy = app.yNumPix/2;

                d_mm = abs((cL(1)-cx) - (cR(1)-cx)) * app.ps;
                Z_mm = (app.b * app.f) / d_mm;
                X_mm = ((cL(1) - cx) * Z_mm / app.f) * app.ps;
                Y_mm = ((cL(2) - cy) * Z_mm / app.f) * app.ps;

                X = X_mm/1000; 
                Y = Y_mm/1000; 
                Z = Z_mm/1000;

                % Distance from origin
                distancesMeasured(i) = Z;
            end

            % Plot
            figure;
            plot(distancesActual, (distancesMeasured-distancesActual), 'o-');
            xlabel('Actual Distance (m)');
            ylabel('Measured Distance (m)');
            title('Stereo Distance Accuracy (3m - 5m)');
            grid on;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxisRight = uiaxes(app.UIFigure);
            title(app.UIAxisRight, 'Title')
            xlabel(app.UIAxisRight, 'X')
            ylabel(app.UIAxisRight, 'Y')
            zlabel(app.UIAxisRight, 'Z')
            app.UIAxisLeft = uiaxes(app.UIFigure);
            title(app.UIAxisLeft, 'Title')
            xlabel(app.UIAxisLeft, 'X')
            ylabel(app.UIAxisLeft, 'Y')
            zlabel(app.UIAxisLeft, 'Z')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CaptureButton = uibutton(app.UIFigure, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Label3';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Label2';
            app.XLabel = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end